classdef draw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SaveButton          matlab.ui.control.Button
        LoadButton          matlab.ui.control.Button
        MSlider             matlab.ui.control.Slider
        MSliderLabel        matlab.ui.control.Label
        Spinner_5           matlab.ui.control.Spinner
        AwSlider            matlab.ui.control.Slider
        AwSliderLabel       matlab.ui.control.Label
        AhSlider            matlab.ui.control.Slider
        AhSliderLabel       matlab.ui.control.Label
        epsilonSlider       matlab.ui.control.Slider
        epsilonSliderLabel  matlab.ui.control.Label
        mSlider             matlab.ui.control.Slider
        mSliderLabel        matlab.ui.control.Label
        Spinner_4           matlab.ui.control.Spinner
        Spinner_3           matlab.ui.control.Spinner
        Spinner_2           matlab.ui.control.Spinner
        Spinner             matlab.ui.control.Spinner
        DrawButton          matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        m
        epsilon
        Aw
        Ah
        M
        S1
        S2
        Myear
        out
    end

    methods (Access = private)

        function fy = fun(app,t,x,r,te,tm,tspan)
            m = r(1);
            ep = r(2);
            Ah = r(3);
            Aw = r(4);
            M = r(5);
            tao = 200;
            q = Ah * ((x(1) + x(2)) / 2 - x(3)) + Aw * (x(1) - x(2));
            q = q/tao;
            tte = interp1(tspan,te,t);
            ttm = interp1(tspan,tm,t);
            fy=[(tte-x(1))/tao+(1-ep)*q*(x(2)-x(1));(tte-x(2))/tao+q*(x(4)-x(2));(ttm-x(3))/tao+ep*q*(x(2)-x(3))/m+(1-ep)*q*(x(1)-x(3))/m;q*(x(3)-x(4))/M];
        end
        function out = get_tspan(app,S1,S2,r)
            result = zeros(size(S1,1)*12,4);
            reste = zeros(size(S1,1)*12,1);
            restm = zeros(size(S1,1)*12,1);
            tspan = zeros(100*12,1);
            te = zeros(100*12,1);
            tm = zeros(100*12,1);
            for it = 1:10
                iter = -app.Myear(1);
                for j = 2:12
                    tspan(j) = tspan(j-1) + getdays(app,-3000000,j);
                end
                for i = iter*1000+1:iter*1000+99
                    for j = 1:12
                        tspan((i-iter*1000)*12 + j) = tspan((i-iter*1000)*12 + j-1) + getdays(app,-3000000,j);
                    end
                end
                for i = iter*1000:iter*1000+99
                    for j = 1:12
                        te((i-iter*1000)*12 + j) = S1(round((iter+300)*10+1),j);
                        tm((i-iter*1000)*12 + j) = S2(round((iter+300)*10+1),j);
                    end
                end
                if it==1
                    y0 = [te(1),te(1),tm(1),tm(1)];
                else
                    y0 = res(end,:);
                end
                [t,x] = ode45(@(t,y)fun(app,t,y,r,te,tm,tspan),tspan,y0);
                res=x(end-11:end,:);
                reste(round((iter+300)*10*12+1):round((iter+300)*10*12+12)) = S1(round((iter+300)*10+1),:);
                restm(round((iter+300)*10*12+1):round((iter+300)*10*12+12)) = S2(round((iter+300)*10+1),:);
            end
            for iter = -app.Myear(1):0.1:-app.Myear(end)
                tspan = zeros(100*12,1);
                te = zeros(100*12,1);
                tm = zeros(100*12,1);
                for j = 2:12
                    tspan(j) = tspan(j-1) + getdays(app,-3000000,j);
                end
                for i = iter*1000+1:iter*1000+99
                    for j = 1:12
                        tspan((i-iter*1000)*12 + j) = tspan((i-iter*1000)*12 + j-1) + getdays(app,-3000000,j);
                    end
                end
                for i = iter*1000:iter*1000+99
                    for j = 1:12
                        te((i-iter*1000)*12 + j) = S1(round((iter+300)*10+1),j);
                        tm((i-iter*1000)*12 + j) = S2(round((iter+300)*10+1),j);
                    end
                end
                if iter==-300
                    y0 = res(end,:);
                else
                    y0 = result(round((iter+300)*10*12),:);
                end
                [t,x] = ode45(@(t,y)fun(app,t,y,r,te,tm,tspan),tspan,y0);
                result(round((iter+300)*10*12+1):round((iter+300)*10*12+12),:)=x(end-11:end,:);
                reste(round((iter+300)*10*12+1):round((iter+300)*10*12+12)) = S1(round((iter+300)*10+1),:);
                restm(round((iter+300)*10*12+1):round((iter+300)*10*12+12)) = S2(round((iter+300)*10+1),:);
                disp(iter);
            end
            out.result = result;
            out.tspan = tspan;
            out.te = reste;
            out.tm = restm;
        end
        function days = getdays(app,y,m)
            if m == 2 && floor(y/4) == y/4 && floor(y/100) ~= y/100 || floor(y/400) ~= y/400
                days = 29;
            else
                dates = [31,28,31,30,31,30,31,31,30,31,30,31];
                days = dates(m);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.m = 10000;
            app.epsilon= 0.3;
            app.Ah = 1.5;
            app.Aw = 4;
            app.M = 100;
            app.mSlider.Value = 4;
            app.epsilonSlider.Value = 0.3;
            app.AhSlider.Value = 1.5;
            app.AwSlider.Value = 4;
            app.MSlider.Value = 2;
            app.Spinner.Value = 10000;
            app.Spinner_2.Value = 0.3;
            app.Spinner_3.Value = 1.5;
            app.Spinner_4.Value = 4;
            app.Spinner_5.Value = 100;
            app.DrawButton.Enable = "off";
            app.SaveButton.Enable='off';
        end

        % Value changed function: mSlider
        function mSliderValueChanged(app, event)
            app.m = 10^app.mSlider.Value;
            app.Spinner.Value = app.m;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.m = app.Spinner.Value;
            app.mSlider.Value = log10(app.m);
        end

        % Value changed function: epsilonSlider
        function epsilonSliderValueChanged(app, event)
            app.epsilon = app.epsilonSlider.Value;
            app.Spinner_2.Value = app.epsilon;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.epsilon = app.Spinner_2.Value;
            app.epsilonSlider.Value = app.epsilon;
        end

        % Value changed function: AhSlider
        function AhSliderValueChanged(app, event)
            app.Ah = app.AhSlider.Value;
            app.Spinner_3.Value = app.Ah;
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            app.Ah = app.Spinner_3.Value;
            app.AhSlider.Value = app.Ah;
        end

        % Value changed function: AwSlider
        function AwSliderValueChanged(app, event)
            app.Aw = app.AwSlider.Value;
            app.Spinner_4.Value = app.Aw;
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            app.Aw = app.Spinner_4.Value;
            app.AwSlider.Value = app.Aw;
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            r = [app.m,app.epsilon,app.Ah / (sum(sum(app.S1-app.S2)) / (size(app.S1,1)*size(app.S1,2))),app.Aw / (sum(sum(app.S1-app.S2)) / (size(app.S1,1)*size(app.S1,2))),app.M];
            app.out = get_tspan(app,app.S1,app.S2,r);
            hold(app.UIAxes,"off");
            plot(app.UIAxes,(1:size(app.out.result,1))/12,app.out.result)
            legend(app.UIAxes,'T1*','T2*','T3*','T4*')
            hold(app.UIAxes2,"off")
            plot(app.UIAxes2,(1:size(app.out.result,1))/12,app.out.te)
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,(1:size(app.out.result,1))/12,app.out.tm)
            legend(app.UIAxes2,'Te','Tm')
            app.SaveButton.Enable='on';
        end

        % Value changed function: MSlider
        function MSliderValueChanged(app, event)
            app.M = 10^(app.MSlider.Value);
            app.Spinner_5.Value = app.M;
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            app.M = app.Spinner_5.Value;
            app.MSlider.Value = log10(app.M);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, filepath]=uigetfile({'*.xlsx';'*.xls';'*.*'},'请选择文件');
            opts = detectImportOptions([filepath filename]);
            opts.Sheet = '30N';
            opts.SelectedVariableNames = [3:14];
            app.S2 = readmatrix([filepath filename],opts);
            opts.Sheet= '赤道';
            app.S1 = readmatrix([filepath filename],opts);
            opts.SelectedVariableNames = [1];
            app.Myear = readmatrix([filepath filename],opts);
            app.Myear(1,:) = [];
            app.S1(1,:) = [];
            app.S2(1,:) = [];
            app.DrawButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, filepath]=uiputfile({'*.xlsx';'*.xls'},'请选择保存位置');
            t1 = zeros(size(app.S1,1),12);
            t2 = zeros(size(app.S1,1),12);
            t3 = zeros(size(app.S1,1),12);
            t4 = zeros(size(app.S1,1),12);
            for i = 1:size(app.S1,1)
                t1(i,:) = app.out.result((i-1)*12+1:i*12,1);
                t2(i,:) = app.out.result((i-1)*12+1:i*12,2);
                t3(i,:) = app.out.result((i-1)*12+1:i*12,3);
                t4(i,:) = app.out.result((i-1)*12+1:i*12,4);
            end
            writematrix(t1,[filepath filename],'Sheet','T1');
            writematrix(t2,[filepath filename],'Sheet','T2');
            writematrix(t3,[filepath filename],'Sheet','T3');
            writematrix(t4,[filepath filename],'Sheet','T4');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'T')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'T')
            zlabel(app.UIAxes2, 'Z')
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 100000];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Step = 0.1;
            app.Spinner_2.Limits = [0 1];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Step = 0.1;
            app.Spinner_3.Limits = [0 1.5];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Step = 0.1;
            app.Spinner_4.Limits = [0 4];
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.mSliderLabel = uilabel(app.UIFigure);
            app.mSliderLabel.Text = 'm';
            app.mSlider = uislider(app.UIFigure);
            app.mSlider.Limits = [0 5];
            app.mSlider.MajorTicks = [0 1 2 3 4 5];
            app.mSlider.MajorTickLabels = {'1', '1e1', '1e2', '1e3', '1e4', '1e5'};
            app.mSlider.ValueChangedFcn = createCallbackFcn(app, @mSliderValueChanged, true);
            app.mSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.epsilonSliderLabel = uilabel(app.UIFigure);
            app.epsilonSliderLabel.Text = 'epsilon';
            app.epsilonSlider = uislider(app.UIFigure);
            app.epsilonSlider.Limits = [0 1];
            app.epsilonSlider.ValueChangedFcn = createCallbackFcn(app, @epsilonSliderValueChanged, true);
            app.AhSliderLabel = uilabel(app.UIFigure);
            app.AhSliderLabel.Text = 'Ah';
            app.AhSlider = uislider(app.UIFigure);
            app.AhSlider.Limits = [0 1.5];
            app.AhSlider.ValueChangedFcn = createCallbackFcn(app, @AhSliderValueChanged, true);
            app.AwSliderLabel = uilabel(app.UIFigure);
            app.AwSliderLabel.Text = 'Aw';
            app.AwSlider = uislider(app.UIFigure);
            app.AwSlider.Limits = [0 4];
            app.AwSlider.ValueChangedFcn = createCallbackFcn(app, @AwSliderValueChanged, true);
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.Step = 0.1;
            app.Spinner_5.Limits = [1 100000];
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_5.Value = 1;
            app.MSliderLabel = uilabel(app.UIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.Limits = [0 5];
            app.MSlider.MajorTicks = [0 1 2 3 4 5];
            app.MSlider.MajorTickLabels = {'1', '1e1', '1e2', '1e3', '1e4', '1e5'};
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end